classdef finalGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OutputsPanel                    matlab.ui.container.Panel
        TabGroup4                       matlab.ui.container.TabGroup
        LogTab                          matlab.ui.container.Tab
        LogTextArea                     matlab.ui.control.TextArea
        LogTextAreaLabel                matlab.ui.control.Label
        ScatterplotsTab                 matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        NophasenoiseTab_2               matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        PhasenoiseTab                   matlab.ui.container.Tab
        Image2                          matlab.ui.control.Image
        PlotsTab                        matlab.ui.container.Tab
        ScenarioviewerTab               matlab.ui.container.Tab
        OutputImagesTab                 matlab.ui.container.Tab
        TabGroup6                       matlab.ui.container.TabGroup
        Sat1DataTab                     matlab.ui.container.Tab
        Image4                          matlab.ui.control.Image
        Sat2DataTab                     matlab.ui.container.Tab
        Image5                          matlab.ui.control.Image
        RunButton                       matlab.ui.control.Button
        SimulationConfigurationPanel    matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        SimTimeTab                      matlab.ui.container.Tab
        HopselectButtonGroup            matlab.ui.container.ButtonGroup
        MultihopButton                  matlab.ui.control.RadioButton
        SinglehopButton                 matlab.ui.control.RadioButton
        DurationhoursEditField          matlab.ui.control.NumericEditField
        DurationhoursEditFieldLabel     matlab.ui.control.Label
        DayEditField                    matlab.ui.control.NumericEditField
        DayEditFieldLabel               matlab.ui.control.Label
        MonthEditField                  matlab.ui.control.NumericEditField
        MonthEditFieldLabel             matlab.ui.control.Label
        YearEditField                   matlab.ui.control.NumericEditField
        YearEditFieldLabel              matlab.ui.control.Label
        SimulationTimeConfigurationLabel  matlab.ui.control.Label
        SatellitesTab                   matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        CommonTab                       matlab.ui.container.Tab
        TransmissionConfigurationLabel  matlab.ui.control.Label
        SystemLossEditField             matlab.ui.control.NumericEditField
        SystemLossEditFieldLabel        matlab.ui.control.Label
        BitRateEditField_2              matlab.ui.control.NumericEditField
        BitRateEditField_2Label         matlab.ui.control.Label
        PowerEditField                  matlab.ui.control.NumericEditField
        PowerEditFieldLabel             matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel         matlab.ui.control.Label
        Sat1Tab                         matlab.ui.container.Tab
        TrueAnomalyEditField            matlab.ui.control.NumericEditField
        TrueAnomalyEditFieldLabel       matlab.ui.control.Label
        ArgofPeriapsisEditField         matlab.ui.control.NumericEditField
        ArgofPeriapsisEditFieldLabel    matlab.ui.control.Label
        RightAscensionEditField         matlab.ui.control.NumericEditField
        RightAscensionEditFieldLabel    matlab.ui.control.Label
        InclinationEditField            matlab.ui.control.NumericEditField
        InclinationEditFieldLabel       matlab.ui.control.Label
        EccentricityEditField           matlab.ui.control.NumericEditField
        EccentricityEditFieldLabel      matlab.ui.control.Label
        SemiMajorAxisEditField          matlab.ui.control.NumericEditField
        SemiMajorAxisEditFieldLabel     matlab.ui.control.Label
        Satellite1ConfigurationLabel    matlab.ui.control.Label
        Sat2Tab_2                       matlab.ui.container.Tab
        TrueAnomalyEditField_2          matlab.ui.control.NumericEditField
        TrueAnomalyEditField_2Label     matlab.ui.control.Label
        ArgofPeriapsisEditField_2       matlab.ui.control.NumericEditField
        ArgofPeriapsisEditField_2Label  matlab.ui.control.Label
        RightAscensionEditField_2       matlab.ui.control.NumericEditField
        RightAscensionEditField_2Label  matlab.ui.control.Label
        InclinationEditField_2          matlab.ui.control.NumericEditField
        InclinationEditField_2Label     matlab.ui.control.Label
        EccentricityEditField_2         matlab.ui.control.NumericEditField
        EccentricityEditField_2Label    matlab.ui.control.Label
        SemiMajorAxisEditField_2        matlab.ui.control.NumericEditField
        SemiMajorAxisEditField_2Label   matlab.ui.control.Label
        Satellite2ConfigurationLabel    matlab.ui.control.Label
        Sat3Tab_2                       matlab.ui.container.Tab
        TrueAnomalyEditField_3          matlab.ui.control.NumericEditField
        TrueAnomalyEditField_3Label     matlab.ui.control.Label
        ArgofPeriapsisEditField_3       matlab.ui.control.NumericEditField
        ArgofPeriapsisEditField_3Label  matlab.ui.control.Label
        RightAscensionEditField_3       matlab.ui.control.NumericEditField
        RightAscensionEditField_3Label  matlab.ui.control.Label
        InclinationEditField_3          matlab.ui.control.NumericEditField
        InclinationEditField_3Label     matlab.ui.control.Label
        EccentricityEditField_3         matlab.ui.control.NumericEditField
        EccentricityEditField_3Label    matlab.ui.control.Label
        SemiMajorAxisEditField_3        matlab.ui.control.NumericEditField
        SemiMajorAxisEditField_3Label   matlab.ui.control.Label
        Satellite3ConfigurationLabel    matlab.ui.control.Label
        GroundStationsTab               matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        GS1Tab                          matlab.ui.container.Tab
        LocationButtonGroup             matlab.ui.container.ButtonGroup
        SKButton                        matlab.ui.control.RadioButton
        ALButton                        matlab.ui.control.RadioButton
        BCButton                        matlab.ui.control.RadioButton
        BitRateEditField_3              matlab.ui.control.NumericEditField
        BitRateEditField_3Label         matlab.ui.control.Label
        PowerEditField_2                matlab.ui.control.NumericEditField
        PowerEditField_2Label           matlab.ui.control.Label
        FrequencyEditField_2            matlab.ui.control.NumericEditField
        FrequencyEditField_2Label       matlab.ui.control.Label
        LongitudeEditField              matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel         matlab.ui.control.Label
        GroundStation1ConfigTxLabel     matlab.ui.control.Label
        LatitudeEditField               matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel          matlab.ui.control.Label
        GS2Tab                          matlab.ui.container.Tab
        LocationButtonGroup_2           matlab.ui.container.ButtonGroup
        QCButton                        matlab.ui.control.RadioButton
        ONButton                        matlab.ui.control.RadioButton
        MBButton                        matlab.ui.control.RadioButton
        LongitudeEditField_2            matlab.ui.control.NumericEditField
        LongitudeEditField_2Label       matlab.ui.control.Label
        LatitudeEditField_2             matlab.ui.control.NumericEditField
        LatitudeEditField_2Label        matlab.ui.control.Label
        GroundStation2ConfigRxLabel     matlab.ui.control.Label
        ImageTab                        matlab.ui.container.Tab
        Image3                          matlab.ui.control.Image
        InputImageLabel                 matlab.ui.control.Label
        ImageDropDown                   matlab.ui.control.DropDown
        ImageDropDownLabel              matlab.ui.control.Label
    end

    
    properties (Access = public)
        startYear = 2023;
        startMonth = 10
        startDay = 21
        simDuration = 5

        isMultihop = false

        satFreq
        satPower
        satBitRate
        satSysLoss
        
        sat1Periapsis
        sat1Axis
        sat1Inclination
        sat1Ascension
        sat1Eccentricity
        sat1Anomaly

        sat2Periapsis
        sat2Axis
        sat2Inclination
        sat2Ascension
        sat2Eccentricity
        sat2Anomaly

        sat3Periapsis
        sat3Axis
        sat3Inclination
        sat3Ascension
        sat3Eccentricity
        sat3Anomaly

        gs1freq
        gs1power
        gs1bitRate
        gs2lat
        gs2long
    end
    
    methods (Access = public)
        
        function results = runSim(app)
            timeStruct = getTimeCfgStruct(app);
            satCmnStruct = getSatCmnCfgStruct(app);
            sat1Struct = getSatCfgStruct(app, app.sat1Periapsis, app.sat1Axis, app.sat1Inclination, app.sat1Ascension, app.sat1Eccentricity, app.sat1Anomaly);
            sat2Struct = getSatCfgStruct(app, app.sat2Periapsis, app.sat2Axis, app.sat2Inclination, app.sat2Ascension, app.sat2Eccentricity, app.sat2Anomaly);
            sat3Struct = getSatCfgStruct(app, app.sat3Periapsis, app.sat3Axis, app.sat3Inclination, app.sat3Ascension, app.sat3Eccentricity, app.sat3Anomaly);
            gsStruct = getGsCfg(app);


            provinceIndex1 = 1;
            provinceIndex2 = 1;
            provinceIndex1, provinceIndex2 = setProvinces(app);
            imageChoice = app.ImageDropDown.ValueIndex;
            plotFigure = 0;
            outputImage1 = -1;
            outputImage2 = -1;
            outString = "";

            clearVisuals(app);

            if app.SinglehopButton.Value

                mainCenter;

                % Define the path to your PNG image (replace with your actual path)
                image1Path = fullfile('figure1.png');
                image2Path = fullfile('figure2.png');
            
                % Read the image using imread
                image = imread(image1Path);
                image2 = imread(image2Path);
              
                % Get a handle to the UIImage component (assuming it's named 'myImage')
                myImage = app.Image;
                my2Image = app.Image2;
              
                % Set the ImageSource property of UIImage to display the image
                myImage.ImageSource = image;
                my2Image.ImageSource = image2;
            else
                mainCenter2;
            end
            app.LogTextArea.Value = outString;
            
            % Copy plot elements to the uiaxes
            h = copyobj(plotFigure.Children, app.PlotsTab);  % Copy all plot elements
            set(h, 'Parent', app.PlotsTab);  % Set parent to the uiaxes
            close(plotFigure);  % Close the invisible figure

            if outputImage1 ~= -1
                % Define the path to your PNG image (replace with your actual path)
                image1Path = fullfile('./out/sat1Img.jpg');
            
                % Read the image using imread
                image = imread(image1Path);
              
                % Get a handle to the UIImage component (assuming it's named 'myImage')
                myImage = app.Image4;
                % Set the ImageSource property of UIImage to display the image
                myImage.ImageSource = image;
            end

            if outputImage2 ~= -1
                % Define the path to your PNG image (replace with your actual path)
                image1Path = fullfile('./out/sat2Img.jpg');
            
                % Read the image using imread
                image = imread(image1Path);
              
                % Get a handle to the UIImage component (assuming it's named 'myImage')
                myImage = app.Image5;
                % Set the ImageSource property of UIImage to display the image
                myImage.ImageSource = image;
                % app.Image5.ImageSource = outputImage2;
                % Copy plot elements to the uiaxes
                % h = copyobj(outputImage2.Children, app.Sat2DataTab);  % Copy all plot elements
                % set(h, 'Parent', app.Sat2DataTab);  % Set parent to the uiaxes
            end

            close all;
            
        end
        
        function attrStruct = getTimeCfgStruct(app)
            attrStruct = struct;
            attrStruct.startYear = app.startYear;
            attrStruct.startMonth = app.startMonth;
            attrStruct.startDay = app.startDay;
            attrStruct.simDuration = app.simDuration;
        end


        function results = updateAttr(app)
            app.startYear = app.YearEditField.Value;
            app.startMonth = app.MonthEditField.Value;
            app.startDay = app.DayEditField.Value;
            app.simDuration = app.DurationhoursEditField.Value;

            app.satFreq = app.SystemLossEditField.Value;
            app.satBitRate = app.BitRateEditField_2.Value;
            app.satPower = app.PowerEditField.Value;
            app.satSysLoss = app.SystemLossEditField.Value;
            
            app.sat1Periapsis= app.ArgofPeriapsisEditField.Value;
            app.sat1Ascension= app.RightAscensionEditField.Value;
            app.sat1Inclination= app.InclinationEditField.Value;
            app.sat1Eccentricity= app.EccentricityEditField.Value;
            app.sat1Axis= app.SemiMajorAxisEditField.Value;
            app.sat1Anomaly = app.TrueAnomalyEditField.Value;

            app.sat2Periapsis= app.ArgofPeriapsisEditField_2.Value;
            app.sat2Ascension= app.RightAscensionEditField_2.Value;
            app.sat2Inclination= app.InclinationEditField_2.Value;
            app.sat2Eccentricity= app.EccentricityEditField_2.Value;
            app.sat2Axis= app.SemiMajorAxisEditField_2.Value;
            app.sat2Anomaly = app.TrueAnomalyEditField_2.Value;

            app.sat3Periapsis= app.ArgofPeriapsisEditField_3.Value;
            app.sat3Ascension= app.RightAscensionEditField_3.Value;
            app.sat3Inclination= app.InclinationEditField_3.Value;
            app.sat3Eccentricity= app.EccentricityEditField_3.Value;
            app.sat3Axis= app.SemiMajorAxisEditField_3.Value;
            app.sat3Anomaly = app.TrueAnomalyEditField_3.Value;
            
            app.gs2lat = app.LatitudeEditField_2.Value;
            app.gs2long = app.LongitudeEditField_2.Value;

            app.gs1freq = app.FrequencyEditField_2.Value;
            app.gs1power = app.PowerEditField_2.Value;
            app.gs1bitRate = app.BitRateEditField_3.Value;

        end
        
        function results = getSatCfgStruct(app, periapsis, axis, inc, asc, ecc, ano)
            results = struct;
            results.periapsis = periapsis;
            results.axis = axis;
            results.inc = inc;
            results.asc = asc;
            results.ecc = ecc;
            results.ano = ano;
        end
        
        function results = getSatCmnCfgStruct(app)
            results = struct;
            results.freq = app.satFreq;
            results.power = app.satPower;
            results.bitRate = app.satBitRate;
            results.sysLoss = app.satSysLoss;
        end
        
        function results = getGsCfg(app)
            results = struct;
            results.gs2Lat = app.gs2lat;
            results.gs2Long = app.gs2long;
            results.gs1Freq = app.gs1freq;
            results.gs1Power = app.gs1power;
            results.gs1BitRate = app.gs1bitRate;
        end
        
        function [gs1Province, gs2Province] = setProvinces(app)
            if app.SKButton.Value
                gs1Province = 1;
            elseif app.ALButton.Value
                gs1Province = 2;
            else
                gs1Province = 3;
            end

            if app.QCButton.Value
                gs2Province = 1;
            elseif app.ONButton.Value
                gs2Province = 2;
            else
                gs2Province = 3;
            end

        end
        
        function results = clearVisuals(app)
            app.LogTextArea.Value = "Loading...";
            app.Image4.ImageSource = zeros(0, 0, 3);
            app.Image5.ImageSource = zeros(0, 0, 3);
            childrenToDelete = get(app.PlotsTab, 'Children');

            % Delete all child objects
            delete(childrenToDelete);
            drawnow;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %runSim(app);
            updateAttr(app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            updateAttr(app);
            runSim(app);
        end

        % Value changed function: YearEditField
        function YearEditFieldValueChanged(app, event)
            value = app.YearEditField.Value;
            app.startYear = value;
        end

        % Value changed function: MonthEditField
        function MonthEditFieldValueChanged(app, event)
            value = app.MonthEditField.Value;
            app.startMonth = value;
        end

        % Value changed function: DayEditField
        function DayEditFieldValueChanged(app, event)
            value = app.DayEditField.Value;
            app.startDay = value;
        end

        % Value changed function: DurationhoursEditField
        function DurationhoursEditFieldValueChanged(app, event)
            value = app.DurationhoursEditField.Value;
            app.simDuration = value;
        end

        % Callback function
        function MultihopCheckBoxValueChanged(app, event)
            value = app.MultihopCheckBox.Value;
            app.isMultihop = value;
        end

        % Value changed function: ImageDropDown
        function ImageDropDownValueChanged(app, event)
            value = app.ImageDropDown.Value;

            % Define the path to your PNG image (replace with your actual path)
            image3Path = fullfile("Images/"+value);

            if value == "Webcam"
                image3Path = fullfile("Images/webcamIcon.png");
            end
        
            % Read the image using imread
            image = imread(image3Path);
          
            % Get a handle to the UIImage component (assuming it's named 'myImage')
            myImage = app.Image3;
          
            % Set the ImageSource property of UIImage to display the image
            myImage.ImageSource = image;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulationConfigurationPanel = uipanel(app.UIFigure);
            app.SimulationConfigurationPanel.Title = 'Simulation Configuration';
            app.TabGroup = uitabgroup(app.SimulationConfigurationPanel);
            app.SimTimeTab = uitab(app.TabGroup);
            app.SimTimeTab.Title = 'Sim Time';
            app.SimulationTimeConfigurationLabel = uilabel(app.SimTimeTab);
            app.SimulationTimeConfigurationLabel.Text = 'Simulation Time Configuration';
            app.YearEditFieldLabel = uilabel(app.SimTimeTab);
            app.YearEditFieldLabel.Text = 'Year';
            app.YearEditField = uieditfield(app.SimTimeTab, 'numeric');
            app.YearEditField.ValueChangedFcn = createCallbackFcn(app, @YearEditFieldValueChanged, true);
            app.YearEditField.Value = 2023;
            app.MonthEditFieldLabel = uilabel(app.SimTimeTab);
            app.MonthEditFieldLabel.Text = 'Month';
            app.MonthEditField = uieditfield(app.SimTimeTab, 'numeric');
            app.MonthEditField.ValueChangedFcn = createCallbackFcn(app, @MonthEditFieldValueChanged, true);
            app.MonthEditField.Value = 10;
            app.DayEditFieldLabel = uilabel(app.SimTimeTab);
            app.DayEditFieldLabel.Text = 'Day';
            app.DayEditField = uieditfield(app.SimTimeTab, 'numeric');
            app.DayEditField.ValueChangedFcn = createCallbackFcn(app, @DayEditFieldValueChanged, true);
            app.DayEditField.Value = 21;
            app.DurationhoursEditFieldLabel = uilabel(app.SimTimeTab);
            app.DurationhoursEditFieldLabel.Text = 'Duration (hours)';
            app.DurationhoursEditField = uieditfield(app.SimTimeTab, 'numeric');
            app.DurationhoursEditField.ValueChangedFcn = createCallbackFcn(app, @DurationhoursEditFieldValueChanged, true);
            app.DurationhoursEditField.Value = 5;
            app.HopselectButtonGroup = uibuttongroup(app.SimTimeTab);
            app.HopselectButtonGroup.Title = 'Hop select';
            app.SinglehopButton = uiradiobutton(app.HopselectButtonGroup);
            app.SinglehopButton.Text = 'Single-hop';
            app.SinglehopButton.Value = true;
            app.MultihopButton = uiradiobutton(app.HopselectButtonGroup);
            app.MultihopButton.Text = 'Multi-hop';
            app.SatellitesTab = uitab(app.TabGroup);
            app.SatellitesTab.Title = 'Satellites';
            app.TabGroup2 = uitabgroup(app.SatellitesTab);
            app.CommonTab = uitab(app.TabGroup2);
            app.CommonTab.Title = 'Common';
            app.FrequencyEditFieldLabel = uilabel(app.CommonTab);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.CommonTab, 'numeric');
            app.FrequencyEditField.Value = 27000000000;
            app.PowerEditFieldLabel = uilabel(app.CommonTab);
            app.PowerEditFieldLabel.Text = 'Power';
            app.PowerEditField = uieditfield(app.CommonTab, 'numeric');
            app.PowerEditField.Value = 20;
            app.BitRateEditField_2Label = uilabel(app.CommonTab);
            app.BitRateEditField_2Label.Text = 'Bit Rate';
            app.BitRateEditField_2 = uieditfield(app.CommonTab, 'numeric');
            app.BitRateEditField_2.Value = 20;
            app.SystemLossEditFieldLabel = uilabel(app.CommonTab);
            app.SystemLossEditFieldLabel.Text = 'System Loss';
            app.SystemLossEditField = uieditfield(app.CommonTab, 'numeric');
            app.SystemLossEditField.Value = 3;
            app.TransmissionConfigurationLabel = uilabel(app.CommonTab);
            app.TransmissionConfigurationLabel.Text = 'Transmission Configuration';
            app.Sat1Tab = uitab(app.TabGroup2);
            app.Sat1Tab.Title = 'Sat 1';
            app.Satellite1ConfigurationLabel = uilabel(app.Sat1Tab);
            app.Satellite1ConfigurationLabel.Text = 'Satellite 1 Configuration';
            app.SemiMajorAxisEditFieldLabel = uilabel(app.Sat1Tab);
            app.SemiMajorAxisEditFieldLabel.Text = 'Semi Major Axis';
            app.SemiMajorAxisEditField = uieditfield(app.Sat1Tab, 'numeric');
            app.SemiMajorAxisEditField.Value = 10000000;
            app.EccentricityEditFieldLabel = uilabel(app.Sat1Tab);
            app.EccentricityEditFieldLabel.Text = 'Eccentricity';
            app.EccentricityEditField = uieditfield(app.Sat1Tab, 'numeric');
            app.InclinationEditFieldLabel = uilabel(app.Sat1Tab);
            app.InclinationEditFieldLabel.Text = 'Inclination';
            app.InclinationEditField = uieditfield(app.Sat1Tab, 'numeric');
            app.InclinationEditField.Value = 60;
            app.RightAscensionEditFieldLabel = uilabel(app.Sat1Tab);
            app.RightAscensionEditFieldLabel.Text = 'Right Ascension';
            app.RightAscensionEditField = uieditfield(app.Sat1Tab, 'numeric');
            app.ArgofPeriapsisEditFieldLabel = uilabel(app.Sat1Tab);
            app.ArgofPeriapsisEditFieldLabel.Text = 'Arg of Periapsis';
            app.ArgofPeriapsisEditField = uieditfield(app.Sat1Tab, 'numeric');
            app.TrueAnomalyEditFieldLabel = uilabel(app.Sat1Tab);
            app.TrueAnomalyEditFieldLabel.Text = 'True Anomaly';
            app.TrueAnomalyEditField = uieditfield(app.Sat1Tab, 'numeric');
            app.Sat2Tab_2 = uitab(app.TabGroup2);
            app.Sat2Tab_2.Title = 'Sat 2';
            app.Satellite2ConfigurationLabel = uilabel(app.Sat2Tab_2);
            app.Satellite2ConfigurationLabel.Text = 'Satellite 2 Configuration';
            app.SemiMajorAxisEditField_2Label = uilabel(app.Sat2Tab_2);
            app.SemiMajorAxisEditField_2Label.Text = 'Semi Major Axis';
            app.SemiMajorAxisEditField_2 = uieditfield(app.Sat2Tab_2, 'numeric');
            app.SemiMajorAxisEditField_2.Value = 10000000;
            app.EccentricityEditField_2Label = uilabel(app.Sat2Tab_2);
            app.EccentricityEditField_2Label.Text = 'Eccentricity';
            app.EccentricityEditField_2 = uieditfield(app.Sat2Tab_2, 'numeric');
            app.InclinationEditField_2Label = uilabel(app.Sat2Tab_2);
            app.InclinationEditField_2Label.Text = 'Inclination';
            app.InclinationEditField_2 = uieditfield(app.Sat2Tab_2, 'numeric');
            app.InclinationEditField_2.Value = 60;
            app.RightAscensionEditField_2Label = uilabel(app.Sat2Tab_2);
            app.RightAscensionEditField_2Label.Text = 'Right Ascension';
            app.RightAscensionEditField_2 = uieditfield(app.Sat2Tab_2, 'numeric');
            app.ArgofPeriapsisEditField_2Label = uilabel(app.Sat2Tab_2);
            app.ArgofPeriapsisEditField_2Label.Text = 'Arg of Periapsis';
            app.ArgofPeriapsisEditField_2 = uieditfield(app.Sat2Tab_2, 'numeric');
            app.TrueAnomalyEditField_2Label = uilabel(app.Sat2Tab_2);
            app.TrueAnomalyEditField_2Label.Text = 'True Anomaly';
            app.TrueAnomalyEditField_2 = uieditfield(app.Sat2Tab_2, 'numeric');
            app.TrueAnomalyEditField_2.Value = -55;
            app.Sat3Tab_2 = uitab(app.TabGroup2);
            app.Sat3Tab_2.Title = 'Sat 3';
            app.Satellite3ConfigurationLabel = uilabel(app.Sat3Tab_2);
            app.Satellite3ConfigurationLabel.Text = 'Satellite 3 Configuration';
            app.SemiMajorAxisEditField_3Label = uilabel(app.Sat3Tab_2);
            app.SemiMajorAxisEditField_3Label.Text = 'Semi Major Axis';
            app.SemiMajorAxisEditField_3 = uieditfield(app.Sat3Tab_2, 'numeric');
            app.SemiMajorAxisEditField_3.Value = 10000000;
            app.EccentricityEditField_3Label = uilabel(app.Sat3Tab_2);
            app.EccentricityEditField_3Label.Text = 'Eccentricity';
            app.EccentricityEditField_3 = uieditfield(app.Sat3Tab_2, 'numeric');
            app.InclinationEditField_3Label = uilabel(app.Sat3Tab_2);
            app.InclinationEditField_3Label.Text = 'Inclination';
            app.InclinationEditField_3 = uieditfield(app.Sat3Tab_2, 'numeric');
            app.InclinationEditField_3.Value = 60;
            app.RightAscensionEditField_3Label = uilabel(app.Sat3Tab_2);
            app.RightAscensionEditField_3Label.Text = 'Right Ascension';
            app.RightAscensionEditField_3 = uieditfield(app.Sat3Tab_2, 'numeric');
            app.ArgofPeriapsisEditField_3Label = uilabel(app.Sat3Tab_2);
            app.ArgofPeriapsisEditField_3Label.Text = 'Arg of Periapsis';
            app.ArgofPeriapsisEditField_3 = uieditfield(app.Sat3Tab_2, 'numeric');
            app.TrueAnomalyEditField_3Label = uilabel(app.Sat3Tab_2);
            app.TrueAnomalyEditField_3Label.Text = 'True Anomaly';
            app.TrueAnomalyEditField_3 = uieditfield(app.Sat3Tab_2, 'numeric');
            app.TrueAnomalyEditField_3.Value = -110;
            app.GroundStationsTab = uitab(app.TabGroup);
            app.GroundStationsTab.Title = 'Ground Stations';
            app.TabGroup3 = uitabgroup(app.GroundStationsTab);
            app.GS1Tab = uitab(app.TabGroup3);
            app.GS1Tab.Title = 'GS 1';
            app.LatitudeEditFieldLabel = uilabel(app.GS1Tab);
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.LatitudeEditField = uieditfield(app.GS1Tab, 'numeric');
            app.GroundStation1ConfigTxLabel = uilabel(app.GS1Tab);
            app.GroundStation1ConfigTxLabel.Text = 'Ground Station 1 Config (Tx)';
            app.LongitudeEditFieldLabel = uilabel(app.GS1Tab);
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.LongitudeEditField = uieditfield(app.GS1Tab, 'numeric');
            app.FrequencyEditField_2Label = uilabel(app.GS1Tab);
            app.FrequencyEditField_2Label.Text = 'Frequency';
            app.FrequencyEditField_2 = uieditfield(app.GS1Tab, 'numeric');
            app.FrequencyEditField_2.Value = 30000000000;
            app.PowerEditField_2Label = uilabel(app.GS1Tab);
            app.PowerEditField_2Label.Text = 'Power';
            app.PowerEditField_2 = uieditfield(app.GS1Tab, 'numeric');
            app.PowerEditField_2.Value = 40;
            app.BitRateEditField_3Label = uilabel(app.GS1Tab);
            app.BitRateEditField_3Label.Text = 'Bit Rate';
            app.BitRateEditField_3 = uieditfield(app.GS1Tab, 'numeric');
            app.BitRateEditField_3.Value = 20;
            app.LocationButtonGroup = uibuttongroup(app.GS1Tab);
            app.LocationButtonGroup.Title = 'Location';
            app.BCButton = uiradiobutton(app.LocationButtonGroup);
            app.BCButton.Text = 'BC';
            app.BCButton.Value = true;
            app.ALButton = uiradiobutton(app.LocationButtonGroup);
            app.ALButton.Text = 'AL';
            app.SKButton = uiradiobutton(app.LocationButtonGroup);
            app.SKButton.Text = 'SK';
            app.GS2Tab = uitab(app.TabGroup3);
            app.GS2Tab.Title = 'GS 2';
            app.GroundStation2ConfigRxLabel = uilabel(app.GS2Tab);
            app.GroundStation2ConfigRxLabel.Text = 'Ground Station 2 Config (Rx)';
            app.LatitudeEditField_2Label = uilabel(app.GS2Tab);
            app.LatitudeEditField_2Label.Text = 'Latitude';
            app.LatitudeEditField_2 = uieditfield(app.GS2Tab, 'numeric');
            app.LatitudeEditField_2.Value = 52.2294963;
            app.LongitudeEditField_2Label = uilabel(app.GS2Tab);
            app.LongitudeEditField_2Label.Text = 'Longitude';
            app.LongitudeEditField_2 = uieditfield(app.GS2Tab, 'numeric');
            app.LongitudeEditField_2.Value = 0.1487094;
            app.LocationButtonGroup_2 = uibuttongroup(app.GS2Tab);
            app.LocationButtonGroup_2.Title = 'Location';
            app.MBButton = uiradiobutton(app.LocationButtonGroup_2);
            app.MBButton.Text = 'MB';
            app.ONButton = uiradiobutton(app.LocationButtonGroup_2);
            app.ONButton.Text = 'ON';
            app.ONButton.Value = true;
            app.QCButton = uiradiobutton(app.LocationButtonGroup_2);
            app.QCButton.Text = 'QC';
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.ImageDropDownLabel = uilabel(app.ImageTab);
            app.ImageDropDownLabel.Text = 'Image';
            app.ImageDropDown = uidropdown(app.ImageTab);
            app.ImageDropDown.Items = {'Flower1.png', 'Flower2.jpeg', 'Mountain.jpeg', 'Color_Wheel.jpg', 'Landscape.jpg', 'Denmark.jpeg', 'Village.jpg', 'Mickey.jpg', 'Shrine.jpg', 'Webcam'};
            app.ImageDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageDropDownValueChanged, true);
            app.ImageDropDown.Value = 'Flower1.png';
            app.InputImageLabel = uilabel(app.ImageTab);
            app.InputImageLabel.Text = 'Input Image';
            app.Image3 = uiimage(app.ImageTab);
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.OutputsPanel = uipanel(app.UIFigure);
            app.OutputsPanel.Title = 'Outputs';
            app.TabGroup4 = uitabgroup(app.OutputsPanel);
            app.LogTab = uitab(app.TabGroup4);
            app.LogTab.Title = 'Log';
            app.LogTextAreaLabel = uilabel(app.LogTab);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.LogTab);
            app.ScatterplotsTab = uitab(app.TabGroup4);
            app.ScatterplotsTab.Title = 'Scatter plots';
            app.TabGroup5 = uitabgroup(app.ScatterplotsTab);
            app.NophasenoiseTab_2 = uitab(app.TabGroup5);
            app.NophasenoiseTab_2.Title = 'No phase noise';
            app.Image = uiimage(app.NophasenoiseTab_2);
            app.PhasenoiseTab = uitab(app.TabGroup5);
            app.PhasenoiseTab.Title = 'Phase noise';
            app.Image2 = uiimage(app.PhasenoiseTab);
            app.PlotsTab = uitab(app.TabGroup5);
            app.PlotsTab.Title = 'Plots';
            app.ScenarioviewerTab = uitab(app.TabGroup4);
            app.ScenarioviewerTab.Title = 'Scenario viewer';
            app.OutputImagesTab = uitab(app.TabGroup4);
            app.OutputImagesTab.Title = 'Output Images';
            app.TabGroup6 = uitabgroup(app.OutputImagesTab);
            app.Sat1DataTab = uitab(app.TabGroup6);
            app.Sat1DataTab.Title = 'Sat 1 Data';
            app.Image4 = uiimage(app.Sat1DataTab);
            app.Sat2DataTab = uitab(app.TabGroup6);
            app.Sat2DataTab.Title = 'Sat 2 Data';
            app.Image5 = uiimage(app.Sat2DataTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end