classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RunButton                     matlab.ui.control.Button
        SimulationConfigurationPanel  matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        SatellitesTab                 matlab.ui.container.Tab
        TabGroup2                     matlab.ui.container.TabGroup
        Sat1Tab                       matlab.ui.container.Tab
        ArgofPeriapsisEditField       matlab.ui.control.NumericEditField
        ArgofPeriapsisEditFieldLabel  matlab.ui.control.Label
        RightAscensionEditField       matlab.ui.control.NumericEditField
        RightAscensionEditFieldLabel  matlab.ui.control.Label
        InclinationEditField          matlab.ui.control.NumericEditField
        InclinationEditFieldLabel     matlab.ui.control.Label
        EccentricityEditField         matlab.ui.control.NumericEditField
        EccentricityEditFieldLabel    matlab.ui.control.Label
        SemiMajorAxisEditField        matlab.ui.control.NumericEditField
        SemiMajorAxisEditFieldLabel   matlab.ui.control.Label
        Satellite1ConfigurationLabel  matlab.ui.control.Label
        Sat2Tab                       matlab.ui.container.Tab
        Sat3Tab                       matlab.ui.container.Tab
        GroundStationsTab             matlab.ui.container.Tab
        TabGroup3                     matlab.ui.container.TabGroup
        GS1Tab                        matlab.ui.container.Tab
        GS2Tab                        matlab.ui.container.Tab
        SimTimeTab                    matlab.ui.container.Tab
        DurationhoursEditField        matlab.ui.control.NumericEditField
        DurationhoursEditFieldLabel   matlab.ui.control.Label
        DayEditField                  matlab.ui.control.NumericEditField
        DayEditFieldLabel             matlab.ui.control.Label
        MonthEditField                matlab.ui.control.NumericEditField
        MonthEditFieldLabel           matlab.ui.control.Label
        YearEditField                 matlab.ui.control.NumericEditField
        YearEditFieldLabel            matlab.ui.control.Label
        SimulationTimeConfigurationLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        startYear = 2023;
        startMonth = 10
        startDay = 21
        simDuration = 5
        
        sat1Periapsis
        sat1Axis
        sat1Inclination
        sat1Ascension
        sat1Eccentricity

        sat2Periapsis
        sat2Axis
        sat2Inclination
        sat2Ascension
        sat2Eccentricity
    end
    
    methods (Access = public)
        
        function results = runSim(app)
            timeStruct = getTimeCfgStruct(app);
            sat1Struct = getSatCfgStruct(app, app.sat1Periapsis, app.sat1Axis, app.sat1Inclination, app.sat1Ascension, app.sat1Eccentricity);
            %sat2Struct = getSatCfgStruct(app, )
            testingSat(timeStruct, sat1Struct);
        end
        
        function attrStruct = getTimeCfgStruct(app)
            attrStruct = struct;
            attrStruct.startYear = app.startYear;
            attrStruct.startMonth = app.startMonth;
            attrStruct.startDay = app.startDay;
            attrStruct.simDuration = app.simDuration;
        end


        function results = updateAttr(app)
            app.startYear = app.YearEditField.Value;
            app.startMonth = app.MonthEditField.Value;
            app.startDay = app.DayEditField.Value;
            app.simDuration = app.DurationhoursEditField.Value;
            
            app.sat1Periapsis= app.ArgofPeriapsisEditField.Value;
            app.sat1Ascension= app.RightAscensionEditField.Value;
            app.sat1Inclination= app.InclinationEditField.Value;
            app.sat1Eccentricity= app.EccentricityEditField.Value;
            app.sat1Axis= app.SemiMajorAxisEditField.Value;

        end
        
        function results = getSatCfgStruct(app, periapsis, axis, inc, asc, ecc)
            results = struct;
            results.periapsis = periapsis;
            results.axis = axis;
            results.inc = inc;
            results.asc = asc;
            results.ecc = ecc;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %runSim(app);
            updateAttr(app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            updateAttr(app);
            runSim(app);
        end

        % Value changed function: YearEditField
        function YearEditFieldValueChanged(app, event)
            value = app.YearEditField.Value;
            app.startYear = value;
        end

        % Value changed function: MonthEditField
        function MonthEditFieldValueChanged(app, event)
            value = app.MonthEditField.Value;
            app.startMonth = value;
        end

        % Value changed function: DayEditField
        function DayEditFieldValueChanged(app, event)
            value = app.DayEditField.Value;
            app.startDay = value;
        end

        % Value changed function: DurationhoursEditField
        function DurationhoursEditFieldValueChanged(app, event)
            value = app.DurationhoursEditField.Value;
            app.simDuration = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulationConfigurationPanel = uipanel(app.UIFigure);
            app.SimulationConfigurationPanel.Title = 'Simulation Configuration';
            app.TabGroup = uitabgroup(app.SimulationConfigurationPanel);
            app.SimTimeTab = uitab(app.TabGroup);
            app.SimTimeTab.Title = 'Sim Time';
            app.SimulationTimeConfigurationLabel = uilabel(app.SimTimeTab);
            app.SimulationTimeConfigurationLabel.Text = 'Simulation Time Configuration';
            app.YearEditFieldLabel = uilabel(app.SimTimeTab);
            app.YearEditFieldLabel.Text = 'Year';
            app.YearEditField = uieditfield(app.SimTimeTab, 'numeric');
            app.YearEditField.ValueChangedFcn = createCallbackFcn(app, @YearEditFieldValueChanged, true);
            app.YearEditField.Value = 2023;
            app.MonthEditFieldLabel = uilabel(app.SimTimeTab);
            app.MonthEditFieldLabel.Text = 'Month';
            app.MonthEditField = uieditfield(app.SimTimeTab, 'numeric');
            app.MonthEditField.ValueChangedFcn = createCallbackFcn(app, @MonthEditFieldValueChanged, true);
            app.MonthEditField.Value = 10;
            app.DayEditFieldLabel = uilabel(app.SimTimeTab);
            app.DayEditFieldLabel.Text = 'Day';
            app.DayEditField = uieditfield(app.SimTimeTab, 'numeric');
            app.DayEditField.ValueChangedFcn = createCallbackFcn(app, @DayEditFieldValueChanged, true);
            app.DayEditField.Value = 21;
            app.DurationhoursEditFieldLabel = uilabel(app.SimTimeTab);
            app.DurationhoursEditFieldLabel.Text = 'Duration (hours)';
            app.DurationhoursEditField = uieditfield(app.SimTimeTab, 'numeric');
            app.DurationhoursEditField.ValueChangedFcn = createCallbackFcn(app, @DurationhoursEditFieldValueChanged, true);
            app.DurationhoursEditField.Value = 5;
            app.SatellitesTab = uitab(app.TabGroup);
            app.SatellitesTab.Title = 'Satellites';
            app.TabGroup2 = uitabgroup(app.SatellitesTab);
            app.Sat1Tab = uitab(app.TabGroup2);
            app.Sat1Tab.Title = 'Sat 1';
            app.Satellite1ConfigurationLabel = uilabel(app.Sat1Tab);
            app.Satellite1ConfigurationLabel.Text = 'Satellite 1 Configuration';
            app.SemiMajorAxisEditFieldLabel = uilabel(app.Sat1Tab);
            app.SemiMajorAxisEditFieldLabel.Text = 'Semi Major Axis';
            app.SemiMajorAxisEditField = uieditfield(app.Sat1Tab, 'numeric');
            app.SemiMajorAxisEditField.Value = 10000000;
            app.EccentricityEditFieldLabel = uilabel(app.Sat1Tab);
            app.EccentricityEditFieldLabel.Text = 'Eccentricity';
            app.EccentricityEditField = uieditfield(app.Sat1Tab, 'numeric');
            app.InclinationEditFieldLabel = uilabel(app.Sat1Tab);
            app.InclinationEditFieldLabel.Text = 'Inclination';
            app.InclinationEditField = uieditfield(app.Sat1Tab, 'numeric');
            app.InclinationEditField.Value = 60;
            app.RightAscensionEditFieldLabel = uilabel(app.Sat1Tab);
            app.RightAscensionEditFieldLabel.Text = 'Right Ascension';
            app.RightAscensionEditField = uieditfield(app.Sat1Tab, 'numeric');
            app.ArgofPeriapsisEditFieldLabel = uilabel(app.Sat1Tab);
            app.ArgofPeriapsisEditFieldLabel.Text = 'Arg of Periapsis';
            app.ArgofPeriapsisEditField = uieditfield(app.Sat1Tab, 'numeric');
            app.Sat2Tab = uitab(app.TabGroup2);
            app.Sat2Tab.Title = 'Sat 2';
            app.Sat3Tab = uitab(app.TabGroup2);
            app.Sat3Tab.Title = 'Sat 3';
            app.GroundStationsTab = uitab(app.TabGroup);
            app.GroundStationsTab.Title = 'Ground Stations';
            app.TabGroup3 = uitabgroup(app.GroundStationsTab);
            app.GS1Tab = uitab(app.TabGroup3);
            app.GS1Tab.Title = 'GS 1';
            app.GS2Tab = uitab(app.TabGroup3);
            app.GS2Tab.Title = 'GS 2';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end